classdef AxyDatabaseTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImportDatabaseButton    matlab.ui.control.Button
        DataPath                matlab.ui.control.Label
        ArchivePath             matlab.ui.control.Label
        DataPathLabel           matlab.ui.control.Label
        ArchivePathLabel        matlab.ui.control.Label
        AxyDatabasePath         matlab.ui.control.EditField
        AxyDatabasePathLabel    matlab.ui.control.Label
        DatabaseName            matlab.ui.control.Label
        DatabaseNameLabel       matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        ImportTab               matlab.ui.container.Tab
        AddMD5HashButton        matlab.ui.control.Button
        fromKRSPEditField       matlab.ui.control.EditField
        fromKRSPEditFieldLabel  matlab.ui.control.Label
        FixSquirrelIDButton     matlab.ui.control.Button
        IssueRowsLabel          matlab.ui.control.Label
        OpenIssuesLabel         matlab.ui.control.Label
        IssuesTable             matlab.ui.control.Table
        ExportTab               matlab.ui.container.Tab
        FilesReadyForExport     matlab.ui.control.Label
        FilesReadyLabel         matlab.ui.control.Label
        UseFields               matlab.ui.control.EditField
        UseFieldsLabel          matlab.ui.control.Label
        ExportFilesButton       matlab.ui.control.Button
        ExportTable             matlab.ui.control.Table
        PrepareExport           matlab.ui.control.Button
        SyncTimingTab           matlab.ui.container.Tab
        rowNLabel               matlab.ui.control.Label
        MarkasSyncedButton      matlab.ui.control.Button
        EventButtonGroup        matlab.ui.container.ButtonGroup
        AllButton               matlab.ui.control.RadioButton
        Shake2Button            matlab.ui.control.RadioButton
        Shake1Button            matlab.ui.control.RadioButton
        RemovedButton           matlab.ui.control.RadioButton
        DeployButton            matlab.ui.control.RadioButton
        FilterButton            matlab.ui.control.Button
        FilterAxyFile           matlab.ui.control.EditField
        FilterEditFieldLabel    matlab.ui.control.Label
        LoadDataButton          matlab.ui.control.Button
        AxyFileDropDown         matlab.ui.control.DropDown
        AxyFileDropDownLabel    matlab.ui.control.Label
        SyncPlot                matlab.ui.control.UIAxes
        StatusLabel             matlab.ui.control.Label
        ConsoleTextArea         matlab.ui.control.TextArea
    end


    properties (Access = public)
        printCount = 0;
        T_AxyDB = [];
        databaseName = "AxyDatabase.xlsx";
        failureLabels = {};
        archiveSubdir = "Archive";
        dataSubdir = "Data";
        T = [];
        axyFileItems = string;
        rowId = 0;
    end

    methods (Access = public)

        function aprint(app,newText,varargin)
            if strcmp(newText,'') == 0
                app.ConsoleTextArea.Value = sprintf("%03d> %s\n%s",app.printCount,newText,strjoin(app.ConsoleTextArea.Value,'\n'));
                app.printCount = app.printCount + 1;
                if nargin > 2
                    app.ConsoleTextArea.BackgroundColor = 'r';
                else
                    app.ConsoleTextArea.BackgroundColor = 'k';
                end
            else
                app.ConsoleTextArea.Value = "";
            end
        end

        function status(app,isProcessing)
            if isProcessing
                app.StatusLabel.Text = 'Processing';
                app.StatusLabel.BackgroundColor = [0 1 0];
                pause(.2);
            else
                app.StatusLabel.Text = 'Idle';
                app.StatusLabel.BackgroundColor = [0.5 0.5 0.5];
            end
        end

        function importDatabase(app)
            dbFilepath = fullfile(app.AxyDatabasePath.Value,app.databaseName);
            if isfile(dbFilepath)
                aprint(app,"importing AxyDatabase.xlsx");
                % override opts
                opts = detectImportOptions(dbFilepath);
                variableNames = opts.VariableNames;
                [variableTypes{1:numel(variableNames)}] = deal('string');
                [variableTypes{contains(variableNames,{'date','time'})}] = deal('datetime');
                variableTypes(contains(variableNames,{'squirrel_id','axy_fs'})) = {'double'};
                variableTypes(contains(variableNames,'sync')) = {'logical'};
                opts.VariableNames = variableNames;
                opts.VariableTypes = variableTypes;
                app.T_AxyDB = readtable(dbFilepath,opts);
                populateIssues(app);
                populateSync(app);
            else
                aprint(app,"no AxyDatabase.xlsx",1);
            end
        end

        function populateIssues(app)
            app.FixSquirrelIDButton.Enable = false;
            app.AddMD5HashButton.Enable = false;
            issueTable = table;
            jj = 0;
            warning ('off','all');
            for ii = 1:size(app.T_AxyDB,1)
                [isValid,failureTypes] = isValidRow(app,ii);
                if ~isValid
                    jj = jj + 1;
                    issueTable.row(jj) = ii + 1; % excel row
                    issueTable.folder(jj) = app.T_AxyDB.folder(ii);
                    issueTable.filename(jj) = app.T_AxyDB.filename(ii);
                    issueTable.issues(jj) = {strjoin(app.failureLabels(failureTypes),', ')};
                    if ismember(3,failureTypes)
                        app.FixSquirrelIDButton.Enable = true;
                    end
                    if ismember(6,failureTypes)
                        app.AddMD5HashButton.Enable = true;
                    end
                end
            end
            warning ('on','all');
            app.IssuesTable.Data = issueTable;
            app.IssueRowsLabel.Text = sprintf("%i Rows",jj);
        end

        function [isValid,failureTypes] = isValidRow(app,ii)
            app.failureLabels{1} = 'file not found';
            app.failureLabels{2} = 'multiple files';
            app.failureLabels{3} = 'squirrel id';
            app.failureLabels{4} = 'Fs';
            app.failureLabels{5} = 'date/time formats';
            app.failureLabels{6} = 'needs md5 hash';
            app.failureLabels{7} = 'power on date';

            failureTypes = [];
            isValid = true;
            n = 0;
            if ~ismissing(app.T_AxyDB.filename(ii))
                % find file
                [~,n] = findArchiveFile(app,ii);
                if n > 1
                    failureTypes = [failureTypes;2];
                    isValid = false;
                elseif n == 0
                    failureTypes = [failureTypes;1];
                    isValid = false;
                end
            else
                % missing is not a failure type, skip it altogether
            end
            if n ~= 0 % only do this if file exists
                if isnan(app.T_AxyDB.squirrel_id(ii))
                    failureTypes = [failureTypes;3];
                    isValid = false;
                end
                if isnan(app.T_AxyDB.axy_fs(ii))
                    failureTypes = [failureTypes;4];
                    isValid = false;
                end
                if ismissing(app.T_AxyDB.axy_d_fmt(ii)) || ismissing(app.T_AxyDB.axy_t_fmt(ii))
                    failureTypes = [failureTypes;5];
                    isValid = false;
                end
                if ismissing(app.T_AxyDB.md5_hash(ii))
                    failureTypes = [failureTypes;6];
                    isValid = false;
                end
                if isnat(app.T_AxyDB.power_on_date(ii))
                    failureTypes = [failureTypes;7];
                    isValid = false;
                end
            end
        end

        function didSave = saveAxyDB(app)
            didSave = false;
            % backup
            answer = questdlg(sprintf('Is %s closed?',app.databaseName),'CLOSE AXY DATABASE','Yes','No','No');
            if strcmp(answer,'Yes')
                T_AxyDB_copy = app.T_AxyDB; % because formatting changes below
                copyfile(fullfile(app.AxyDatabasePath.Value,app.databaseName),...
                    fullfile(app.AxyDatabasePath.Value,app.databaseName+".backup"));
                aprint(app,"backed up database");
                % save new
                dateFmt = "dd/MM/yyyy";
                timeFmt = "HH:mm:ss";
                % can not get this to work dynamically
                app.T_AxyDB.power_on_date = string(app.T_AxyDB.power_on_date,dateFmt);
                app.T_AxyDB.deploy_date = string(app.T_AxyDB.deploy_date,dateFmt);
                app.T_AxyDB.removed_date = string(app.T_AxyDB.removed_date,dateFmt);
                app.T_AxyDB.power_off_date = string(app.T_AxyDB.power_off_date,dateFmt);
                app.T_AxyDB.date_signature = string(app.T_AxyDB.date_signature,dateFmt);
                app.T_AxyDB.power_on_time = string(app.T_AxyDB.power_on_time,timeFmt);
                app.T_AxyDB.deploy_time = string(app.T_AxyDB.deploy_time,timeFmt);
                app.T_AxyDB.removed_time = string(app.T_AxyDB.removed_time,timeFmt);
                app.T_AxyDB.power_off_time = string(app.T_AxyDB.power_off_time,timeFmt);
                app.T_AxyDB.shake1_start_time = string(app.T_AxyDB.shake1_start_time,timeFmt);
                app.T_AxyDB.shake1_stop_time = string(app.T_AxyDB.shake1_stop_time,timeFmt);
                app.T_AxyDB.shake2_start_time = string(app.T_AxyDB.shake2_start_time,timeFmt);
                app.T_AxyDB.shake2_stop_time = string(app.T_AxyDB.shake2_stop_time,timeFmt);
                writetable(app.T_AxyDB,fullfile(app.AxyDatabasePath.Value,app.databaseName));
                aprint(app,"saved database");
                app.T_AxyDB = T_AxyDB_copy;
                didSave = true;
            else
                aprint(app,"close axy database",true);
            end
        end
        
        function [files,n] = findArchiveFile(app,ii)
            [files,n] = dir3(fullfile(app.ArchivePath.Text,app.T_AxyDB.folder(ii)),app.T_AxyDB.filename(ii));
        end
        
        function success = updatePaths(app)
            app.ArchivePath.Text = fullfile(app.AxyDatabasePath.Value,app.archiveSubdir);
            app.DataPath.Text = fullfile(app.AxyDatabasePath.Value,app.dataSubdir);
            app.DatabaseName.Text = app.databaseName;
            if isfile(fullfile(app.AxyDatabasePath.Value,app.databaseName)) &&...
                    isfolder(fullfile(app.DataPath.Text)) && isfolder(fullfile(app.ArchivePath.Text))
                app.ImportDatabaseButton.Enable = true;
                aprint(app,"folder structure verified");
                success = true;
            else
                app.ImportDatabaseButton.Enable = false;
                aprint(app,"CAN NOT PROCEED",true);
                aprint(app,"bad folder structure",true);
                success = false;
            end
        end
        
        function prepareExport(app)
            validRows = [];
            for ii = 1:size(app.T_AxyDB,1)
                useFields = strsplit(app.UseFields.Value,',');
                validRows(ii) = true;
                for jj = 1:numel(useFields)
                    colNum = find(strcmp(app.T_AxyDB.Properties.VariableNames,useFields{jj}));
                    if ~isempty(colNum) && numel(colNum) == 1
                        if ismissing(string(app.T_AxyDB{ii,colNum}))
                            validRows(ii) = false;
                            break;
                        end
                    else
                        aprint(app,"invalid fields, exiting",true);
                        status(app,false);
                        return;
                    end
                end
            end
            validTable = table;
            kk = 0;
            warning ('off','all');
            for ii = 1:size(app.T_AxyDB,1)
                if validRows(ii)
                    useFields = strsplit(app.UseFields.Value,',');
                    exportedFile = string;
                    for jj = 1:numel(useFields)
                        colNum = strcmp(app.T_AxyDB.Properties.VariableNames,useFields{jj});
                        rowData = app.T_AxyDB{ii,colNum};
                        if isdatetime(rowData)
                            exportedFile(jj,:) = string(rowData,"yyyyMMdd");
                        else
                            exportedFile(jj,:) = string(rowData);
                        end
                    end
                    exportedFile = strjoin(exportedFile,'_')+'.csv';
                    if ~isfile(fullfile(app.DataPath.Text,exportedFile))
                        kk = kk + 1;
                        validTable.row(kk) = ii+1;
                        validTable.archive_file(kk) = app.T_AxyDB.filename(ii);
                        validTable.export_file(kk) = exportedFile;
                    end
                end
            end
            warning ('on','all');
            app.ExportTable.Data = validTable;
            app.FilesReadyForExport.Text = string(kk);
            if kk > 0
                app.ExportFilesButton.Enable = true;
            else
                app.ExportFilesButton.Enable = false;
            end
        end
        
        function populateSync(app)
            app.axyFileItems = string;
            jj = 0;
            for ii = 1:size(app.T_AxyDB,1)
                if ~ismissing(app.T_AxyDB.md5_hash(ii)) && ~app.T_AxyDB.sync_verified(ii)
                    [file,n] = dir3(app.DataPath.Text,"*" + app.T_AxyDB.md5_hash(ii) + "*");
                    if n == 1 && contains(file.name(1),app.FilterAxyFile.Value)
                        jj = jj + 1;
                        app.axyFileItems(jj,:) = file.name(1);
                    end
                end
            end
            app.axyFileItems = sort(app.axyFileItems);
            app.AxyFileDropDown.Items = app.axyFileItems;
            app.AxyFileDropDownValueChanged();
        end
        
        function rowId = fileRow(app,dataFile)
            [~,name] = fileparts(dataFile);
            parts = strsplit(name,'_');
            rowId = find(strcmp(app.T_AxyDB.md5_hash,parts{end}));
        end
        
        function syncPlot(app)
            if ~isempty(app.T)
                if any(contains(app.T.Properties.VariableNames,'odba'))
                    plot(app.SyncPlot,app.T.datetime,app.T.odba,'k-');
                    legend(app.SyncPlot,'ODBA','AutoUpdate','off');
                elseif sum(ismember(app.T.Properties.VariableNames,{'x','y','z'})) == 3
                    plot(app.SyncPlot,app.T.datetime,app.T.x,'-');
                    hold(app.SyncPlot,'on');
                    plot(app.SyncPlot,app.T.datetime,app.T.y,'-');
                    plot(app.SyncPlot,app.T.datetime,app.T.z,'-');
                    legend(app.SyncPlot,{'x','y','z'},'AutoUpdate','off');
                    hold(app.SyncPlot,'off');
                end
                grid(app.SyncPlot,'on');
                xlabel(app.SyncPlot,'time');

                % disable
                if isempty(app.T_AxyDB.deploy_time(app.rowId))
                    app.DeployButton.Enable = false;
                end
                if isempty(app.T_AxyDB.removed_time(app.rowId))
                    app.RemovedButton.Enable = false;
                end

                shakeDate = NaT;
                if ~isempty(app.T_AxyDB.date_signature(app.rowId))
                    shakeDate = app.T_AxyDB.date_signature(app.rowId);
                elseif ~isempty(app.T_AxyDB.removed_date(app.rowId))
                    shakeDate = app.T_AxyDB.removed_date(app.rowId);
                end

                if isempty(app.T_AxyDB.shake1_stop_time(app.rowId)) || isnat(shakeDate)
                    app.Shake1Button.Enable = false;
                end
                if isempty(app.T_AxyDB.shake2_stop_time(app.rowId)) || isnat(shakeDate)
                    app.Shake2Button.Enable = false;
                end

                nMin = NaN;
                if app.DeployButton.Value && app.DeployButton.Enable
                    nMin = 60;
                    d = app.T_AxyDB.deploy_date(app.rowId);
                    t = app.T_AxyDB.deploy_time(app.rowId);
                elseif app.RemovedButton.Value && app.RemovedButton.Enable
                    nMin = 60;
                    d = app.T_AxyDB.removed_date(app.rowId);
                    t = app.T_AxyDB.removed_time(app.rowId);
                elseif app.Shake1Button.Value && app.Shake1Button.Enable
                    nMin = 5; % shorten
                    d = app.T_AxyDB.date_signature(app.rowId); % no shake date
                    t = app.T_AxyDB.shake1_stop_time(app.rowId);
                elseif app.Shake2Button.Value && app.Shake2Button.Enable
                    nMin = 5; % shorten
                    d = app.T_AxyDB.date_signature(app.rowId);
                    t = app.T_AxyDB.shake2_stop_time(app.rowId);
                else
                    % use deploy for line
                    d = app.T_AxyDB.deploy_date(app.rowId);
                    t = app.T_AxyDB.deploy_time(app.rowId);
                end
                
                focusDt = datetime(year(d),month(d),day(d),hour(t),minute(t),second(t));
                xline(app.SyncPlot,focusDt,'k-','linewidth',2);
                title(app.SyncPlot,string(focusDt, 'MMMM d, yyyy HH:mm:ss'));

                if ~isnan(nMin)
                    xlim(app.SyncPlot,[focusDt-minutes(nMin),focusDt+minutes(nMin)]);
                else
                    xlim(app.SyncPlot,[min(app.T.datetime),max(app.T.datetime)]);
                end
            end
        end
        
        function virtualSuccess = addVirtualData(app)
            virtualSuccess = true;
            % nSmooth = 60;
            % if ~any(strcmp(app.T.Properties.VariableNames,'odba'))
            %     if sum(ismember(app.T.Properties.VariableNames,{'x','y','z'})) == 3
            %         app.T.odba = abs(X-medfilt1(app.T.x,nSmooth))...
            %             + abs(Y-medfilt1(app.T.y,nSmooth))...
            %             + abs(Z-medfilt1(app.T.z,nSmooth));
            %     end
            % end
            if ~any(strcmp(app.T.Properties.VariableNames,'datetime'))
                
            end
            % fix locale
        end
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.Visible = false; % need this on to develop, turn off right away
            aprint(app,"Hello, Squirrel");
            updatePaths(app);
            status(app,false);
        end

        % Button pushed function: ImportDatabaseButton
        function ImportDatabaseButtonPushed(app, event)
            status(app,true);
            importDatabase(app);
            app.TabGroup.Visible = true;
            status(app,false);
        end

        % Button pushed function: FixSquirrelIDButton
        function FixSquirrelIDButtonPushed(app, event)
            status(app,true);
            aprint(app,'');
            krspFile = fullfile(app.AxyDatabasePath.Value,app.fromKRSPEditField.Value);
            if isfile(krspFile)
                aprint(app,"reading krsp file");

                opts = detectImportOptions(krspFile);
                opts.VariableTypes(contains(opts.VariableNames,'tag')) = {deal('string')};
                T_KRSP = readtable(krspFile,opts);
                hasIssueCount = 0;
                fixedIssueCount = 0;
                for ii = 1:size(app.T_AxyDB,1)
                    if ~ismissing(app.T_AxyDB.filename(ii)) && isnan(app.T_AxyDB.squirrel_id(ii))
                        hasIssueCount = hasIssueCount + 1;
                        aprint(app,".");
                        if ismissing(app.T_AxyDB.tag_right(ii)) && ismissing(app.T_AxyDB.tag_left(ii))
                            aprint(app,sprintf("no tags row %i",ii+1));
                        else
                            matchIdx = find(strcmp(app.T_AxyDB.tag_left(ii),T_KRSP.taglft) & strcmp(app.T_AxyDB.tag_right(ii),T_KRSP.tagrt));
                            if numel(matchIdx) == 1
                                aprint(app,sprintf("fixing row %i",ii+1));
                                app.T_AxyDB.squirrel_id(ii) = T_KRSP.id(matchIdx);
                                fixedIssueCount = fixedIssueCount + 1;
                            elseif numel(matchIdx) > 1
                                for jj = 1:numel(matchIdx)
                                    aprint(app,sprintf("may be: %i-%s-%s",...
                                        T_KRSP.id(matchIdx(jj)),T_KRSP.gr{matchIdx(jj)},T_KRSP.reflo{matchIdx(jj)}));
                                end
                                aprint(app,sprintf("--MULT IDS ROW %i--",ii+1));
                            else
                                matchIdx = [];
                                if strcmp(app.T_AxyDB.tag_left(ii),'-') == 0
                                    tMatch = find(strcmp(app.T_AxyDB.tag_left(ii),T_KRSP.taglft));
                                    matchIdx = [matchIdx;tMatch]; %#ok<*AGROW>
                                end
                                if strcmp(app.T_AxyDB.tag_right(ii),'-') == 0
                                    tMatch = find(strcmp(app.T_AxyDB.tag_right(ii),T_KRSP.tagrt));
                                    matchIdx = [matchIdx;tMatch];
                                end
                                if isempty(matchIdx)
                                    aprint(app,sprintf("no match row %i",ii+1));
                                else
                                    for jj = 1:numel(matchIdx)
                                        aprint(app,sprintf("may be: %i-%s-%s",...
                                            T_KRSP.id(matchIdx(jj)),T_KRSP.gr{matchIdx(jj)},T_KRSP.reflo{matchIdx(jj)}));
                                    end
                                    aprint(app,sprintf("--PARTIAL TAG ROW %i--",ii+1));
                                end
                            end
                        end
                    end
                end
                % done fixing issues
                aprint(app,sprintf("fixed %i/%i squirrel ids",fixedIssueCount,hasIssueCount));
                if fixedIssueCount > 0 && saveAxyDB(app)
                    populateIssues(app);
                else
                    aprint(app,"no fixes, not saving");
                end
            else
                aprint(app,"no KRSP file",true);
            end
            status(app,false);
        end

        % Button pushed function: AddMD5HashButton
        function AddMD5HashButtonPushed(app, event)
            status(app,true);
            hashesUpdated = 0;
            if ~isunix
                aprint(app,"need unix OS",true);
            else
                for ii = 1:size(app.T_AxyDB,1)
                    [~,failureTypes] = isValidRow(app,ii);
                    [files,n] = findArchiveFile(app,ii);
                    if ismember(6,failureTypes) && n == 1
                        [status,cmdout] = system(sprintf('md5 "%s"',files.fullfile(1))); % unix
                        if status == 0
                            cmdParts = strsplit(strtrim(cmdout));
                            app.T_AxyDB.md5_hash(ii) = string(cmdParts(end));
                            hashesUpdated = hashesUpdated + 1;
                            aprint(app,"row %i hash complete",ii+1);
                        end
                    end
                end
                aprint(app,sprintf("%i hashes made",hashesUpdated));
            end
            if hashesUpdated > 0 && saveAxyDB(app)
                populateIssues(app);
            end
            status(app,false);
        end

        % Value changed function: AxyDatabasePath
        function AxyDatabasePathValueChanged(app, event)
            updatePaths(app);
        end

        % Button pushed function: PrepareExport
        function PrepareExportButtonPushed(app, event)
            status(app,true);
            prepareExport(app);
            status(app,false);
        end

        % Button pushed function: ExportFilesButton
        function ExportFilesButtonPushed(app, event)
            status(app,true);
            for ii = 1:size(app.ExportTable.Data,1)
                axyRow = app.ExportTable.Data.row(ii)-1; % convert to T_AxyDB row
                [files,n] = findArchiveFile(app,axyRow);
                if n == 1
                    copyfile(fullfile(files.fullfile(1)),fullfile(app.DataPath.Text,app.ExportTable.Data.export_file(ii)));
                    aprint(app,sprintf("cp %s",app.ExportTable.Data.archive_file(ii)));
                    pause(.01);
                end
            end
            prepareExport(app);
            status(app,false);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            status(app,true);
            dataFile = fullfile(app.DataPath.Text,app.AxyFileDropDown.Value);
            app.rowId = fileRow(app,dataFile);
            if ~isempty(app.rowId)
                opts = detectImportOptions(dataFile);
                headerLabels = strsplit(app.T_AxyDB.header_labels(app.rowId),',');
                if numel(opts.VariableNames) == numel(headerLabels)
                    headerFormat = string;
                    for ii = 1:numel(headerLabels)
                        if strcmp(headerLabels{ii},'datetime')   
                            headerFormat(ii,:) = "%{" + app.T_AxyDB.axy_d_fmt(app.rowId) + " " + app.T_AxyDB.axy_t_fmt(app.rowId) + "}D";
                        elseif strcmp(headerLabels{ii},'date')
                            headerFormat(ii,:) = app.T_AxyDB.axy_d_fmt(app.rowId);
                        elseif strcmp(headerLabels{ii},'time')
                            headerFormat(ii,:) = app.T_AxyDB.axy_t_fmt(app.rowId);
                        elseif any(strcmp(headerLabels{ii},{'temp','x','y','z','odba','battery'}))
                            headerFormat(ii,:) = "%f";
                        else
                            headerFormat(ii,:) = "%s";
                        end
                    end
                    app.T = readtable(dataFile,'Format',strjoin(headerFormat,opts.Delimiter));
                    app.T.Properties.VariableNames = headerLabels;
                    % addVirtualData(app);
                    syncPlot(app);
                else
                    aprint(app,"header length mismatch",false);
                end
            else
                aprint(app,"no database match",false);
            end
            app.MarkasSyncedButton.Enable = true;
            status(app,false);
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            status(app,true);
            app.AxyFileDropDown.Items = app.axyFileItems(contains(app.axyFileItems,app.FilterAxyFile.Value));
            status(app,false);
        end

        % Selection changed function: EventButtonGroup
        function EventButtonGroupSelectionChanged(app, event)
            status(app,true);
            syncPlot(app);
            status(app,false);
        end

        % Button pushed function: MarkasSyncedButton
        function MarkasSyncedButtonPushed(app, event)
            status(app,true);
            app.T_AxyDB.sync_verified(app.rowId) = true;
            didSave = saveAxyDB(app);
            if didSave
                app.AllButton.Value = true;
                cla(app.SyncPlot);
                populateSync(app);
                syncPlot(app);
            end
            status(app,false);
        end

        % Value changed function: AxyFileDropDown
        function AxyFileDropDownValueChanged(app, event)
            dataFile = fullfile(app.DataPath.Text,app.AxyFileDropDown.Value);
            app.rowNLabel.Text = sprintf("row = %s",num2str(fileRow(app,dataFile)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConsoleTextArea = uitextarea(app.UIFigure);
            app.ConsoleTextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.IssuesTable = uitable(app.ImportTab);
            app.IssuesTable.ColumnName = {'row'; 'folder'; 'filename'; 'issues'};
            app.IssuesTable.RowName = {};
            app.OpenIssuesLabel = uilabel(app.ImportTab);
            app.OpenIssuesLabel.Text = 'Open Issues:';
            app.IssueRowsLabel = uilabel(app.ImportTab);
            app.IssueRowsLabel.Text = '0 Rows';
            app.FixSquirrelIDButton = uibutton(app.ImportTab, 'push');
            app.FixSquirrelIDButton.ButtonPushedFcn = createCallbackFcn(app, @FixSquirrelIDButtonPushed, true);
            app.FixSquirrelIDButton.Enable = 'off';
            app.FixSquirrelIDButton.Text = 'Fix Squirrel ID';
            app.fromKRSPEditFieldLabel = uilabel(app.ImportTab);
            app.fromKRSPEditFieldLabel.Text = 'from';
            app.fromKRSPEditField = uieditfield(app.ImportTab, 'text');
            app.fromKRSPEditField.Value = '/Other/krsp_squirrel.csv';
            app.AddMD5HashButton = uibutton(app.ImportTab, 'push');
            app.AddMD5HashButton.ButtonPushedFcn = createCallbackFcn(app, @AddMD5HashButtonPushed, true);
            app.AddMD5HashButton.Enable = 'off';
            app.AddMD5HashButton.Text = 'Add MD5 Hash';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.PrepareExport = uibutton(app.ExportTab, 'push');
            app.PrepareExport.ButtonPushedFcn = createCallbackFcn(app, @PrepareExportButtonPushed, true);
            app.PrepareExport.Text = 'Prepare Export';
            app.ExportTable = uitable(app.ExportTab);
            app.ExportTable.ColumnName = {'row'; 'archive_file'; 'export_file'};
            app.ExportTable.RowName = {};
            app.ExportFilesButton = uibutton(app.ExportTab, 'push');
            app.ExportFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFilesButtonPushed, true);
            app.ExportFilesButton.Enable = 'off';
            app.ExportFilesButton.Text = 'Export Files';
            app.UseFieldsLabel = uilabel(app.ExportTab);
            app.UseFieldsLabel.Text = 'Use fields';
            app.UseFields = uieditfield(app.ExportTab, 'text');
            app.UseFields.Value = 'power_on_date,squirrel_id,axy,md5_hash';
            app.FilesReadyLabel = uilabel(app.ExportTab);
            app.FilesReadyLabel.Text = 'Files ready:';
            app.FilesReadyForExport = uilabel(app.ExportTab);
            app.FilesReadyForExport.Text = '0';
            app.SyncTimingTab = uitab(app.TabGroup);
            app.SyncTimingTab.Title = 'Sync Timing';
            app.SyncPlot = uiaxes(app.SyncTimingTab);
            title(app.SyncPlot, 'Axy Data')
            xlabel(app.SyncPlot, 'X')
            ylabel(app.SyncPlot, 'Y')
            zlabel(app.SyncPlot, 'Z')
            app.AxyFileDropDownLabel = uilabel(app.SyncTimingTab);
            app.AxyFileDropDownLabel.Text = 'Axy File';
            app.AxyFileDropDown = uidropdown(app.SyncTimingTab);
            app.AxyFileDropDown.Items = {'filename'};
            app.AxyFileDropDown.ValueChangedFcn = createCallbackFcn(app, @AxyFileDropDownValueChanged, true);
            app.AxyFileDropDown.Value = 'filename';
            app.LoadDataButton = uibutton(app.SyncTimingTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.FilterEditFieldLabel = uilabel(app.SyncTimingTab);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterAxyFile = uieditfield(app.SyncTimingTab, 'text');
            app.FilterButton = uibutton(app.SyncTimingTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.EventButtonGroup = uibuttongroup(app.SyncTimingTab);
            app.EventButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventButtonGroupSelectionChanged, true);
            app.EventButtonGroup.Title = 'Event';
            app.DeployButton = uiradiobutton(app.EventButtonGroup);
            app.DeployButton.Text = 'Deploy';
            app.DeployButton.Value = true;
            app.RemovedButton = uiradiobutton(app.EventButtonGroup);
            app.RemovedButton.Text = 'Removed';
            app.Shake1Button = uiradiobutton(app.EventButtonGroup);
            app.Shake1Button.Text = 'Shake 1';
            app.Shake2Button = uiradiobutton(app.EventButtonGroup);
            app.Shake2Button.Text = 'Shake 2';
            app.AllButton = uiradiobutton(app.EventButtonGroup);
            app.AllButton.Text = 'All';
            app.MarkasSyncedButton = uibutton(app.SyncTimingTab, 'push');
            app.MarkasSyncedButton.ButtonPushedFcn = createCallbackFcn(app, @MarkasSyncedButtonPushed, true);
            app.MarkasSyncedButton.Enable = 'off';
            app.MarkasSyncedButton.Text = 'Mark as Synced';
            app.rowNLabel = uilabel(app.SyncTimingTab);
            app.rowNLabel.Text = 'row = N';
            app.DatabaseNameLabel = uilabel(app.UIFigure);
            app.DatabaseNameLabel.Text = 'Database Name:';
            app.DatabaseName = uilabel(app.UIFigure);
            app.DatabaseName.Text = 'empty';
            app.AxyDatabasePathLabel = uilabel(app.UIFigure);
            app.AxyDatabasePathLabel.Text = 'Axy Database Path';
            app.AxyDatabasePath = uieditfield(app.UIFigure, 'text');
            app.AxyDatabasePath.ValueChangedFcn = createCallbackFcn(app, @AxyDatabasePathValueChanged, true);
            app.AxyDatabasePath.Value = '/Volumes/GAIDICASSD/KRSP/Axy Database';
            app.ArchivePathLabel = uilabel(app.UIFigure);
            app.ArchivePathLabel.Text = 'Archive Path';
            app.DataPathLabel = uilabel(app.UIFigure);
            app.DataPathLabel.Text = 'Data Path';
            app.ArchivePath = uilabel(app.UIFigure);
            app.ArchivePath.Text = 'empty';
            app.DataPath = uilabel(app.UIFigure);
            app.DataPath.Text = 'empty';
            app.ImportDatabaseButton = uibutton(app.UIFigure, 'push');
            app.ImportDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDatabaseButtonPushed, true);
            app.ImportDatabaseButton.Enable = 'off';
            app.ImportDatabaseButton.Text = 'Import Database';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AxyDatabaseTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end