classdef algorithmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DefaultsButton            matlab.ui.control.Button
        PeriEventDataButtonGroup  matlab.ui.container.ButtonGroup
        NestButton                matlab.ui.control.RadioButton
        SenseButton               matlab.ui.control.RadioButton
        FixTransitionsCheckBox    matlab.ui.control.CheckBox
        SenseThreshSlider         matlab.ui.control.Slider
        SenseThreshSliderLabel    matlab.ui.control.Label
        ExportButton              matlab.ui.control.Button
        PasteButton               matlab.ui.control.Button
        PathEditField             matlab.ui.control.EditField
        PathEditFieldLabel        matlab.ui.control.Label
        PrevDay                   matlab.ui.control.Button
        NextDay                   matlab.ui.control.Button
        KmeanswSlider             matlab.ui.control.Slider
        KmeanswSliderLabel        matlab.ui.control.Label
        SimilarityLabel           matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        RecalcButton              matlab.ui.control.Button
        SmODBASlider              matlab.ui.control.Slider
        SmODBASliderLabel         matlab.ui.control.Label
        SmTempGradSlider          matlab.ui.control.Slider
        SmTempGradSliderLabel     matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        ImportButton              matlab.ui.control.Button
        ODBAwSlider               matlab.ui.control.Slider
        ODBAwSliderLabel          matlab.ui.control.Label
        TempGradwSlider           matlab.ui.control.Slider
        TempGradwSliderLabel      matlab.ui.control.Label
        DayDropDown               matlab.ui.control.DropDown
        DayDropDownLabel          matlab.ui.control.Label
        TempwSlider               matlab.ui.control.Slider
        TempwSliderLabel          matlab.ui.control.Label
        DataFileDropDown          matlab.ui.control.DropDown
        DataFileDropDownLabel     matlab.ui.control.Label
        dataNestPeriods2          matlab.ui.control.UIAxes
        dataNestPeriods1          matlab.ui.control.UIAxes
        compSuppAxes              matlab.ui.control.UIAxes
        compAxes                  matlab.ui.control.UIAxes
        dataSuppAxes              matlab.ui.control.UIAxes
        dataAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        T = table; % Description
        binNestSense = [];
        temp = [];
        nest = [];
        odba = [];
        resetAxes = false;
        sense = {};
        senseParams = {};
        senseParams_default = {};
        saveFilename = "config.mat";
        cacheFilename = "algorithmApp_cache.mat";
        configPath = "";
        colorOn = [0 1 0];
        colorOff = [0.9,0.9,0.9]
        adj_binNestSense = [];
    end
    
    methods (Access = public)
        function updateData(app)
            if isempty(app.T)
                return;
            end
            app.Lamp.Color = app.colorOn;
            pause(0.1);
            startSample = 1 + (str2double(app.DayDropDown.Value)-1)*86400;
            useSamples = 86400;
            endSample = min([startSample+useSamples-1,numel(app.nest)]);
            useRange = startSample:endSample;
            tempRange = app.temp(useRange);
            % oldNestRange = strcmp(T.Nest(useRange),'Nest');
            odbaRange = app.odba(useRange);
            
            setSenseParams(app);
            [app.binNestSense,app.sense] = nestSenseAlg(app.temp,app.odba,app.nest,app.senseParams);
            
            t = linspace((min(useRange)-1)/60,(max(useRange)-1)/60,numel(useRange));
            colors = lines(5);
            yyaxis(app.dataAxes,'left');
            ln1 = plot(app.dataAxes,t,odbaRange,'-');
            hold(app.dataAxes,'on');
            ln2 = plot(app.dataAxes,t,app.sense.nest(useRange),'k-','linewidth',2);
            grid(app.dataAxes,'on');
            xlabel(app.dataAxes,'Time (min)');
            ylabel(app.dataAxes,'p(Nest)');
            if app.resetAxes
                ylim(app.dataAxes,'auto');
            end
            hold(app.dataAxes,'off');
            
            yyaxis(app.dataAxes,'right');
            ln4 = plot(app.dataAxes,t,normalize(tempRange),'-','color','r','linewidth',1);
            hold(app.dataAxes,'on');
            ln5 = plot(app.dataAxes,t,app.binNestSense(useRange),'-','color',colors(5,:),'linewidth',2);
            if isempty(app.adj_binNestSense)
                ln6_data = app.nest;
                ln6_label = "Orig";
            else
                ln6_data = app.adj_binNestSense;
                ln6_label = "Adjust";
            end
            ln6 = plot(app.dataAxes,t,ln6_data(useRange)+1.1,'-','color',[colors(4,:),0.4],'linewidth',2);
            set(app.dataAxes,'ycolor','k');
            yticks(app.dataAxes,[0,1]);
            ylim(app.dataAxes,[-4 3]);
            if app.resetAxes
                xlim(app.dataAxes,[min(t),max(t)]);
                ylim(app.dataAxes,[-4 3]);
            end
            yticklabels(app.dataAxes,{'Out','In'});
            legend(app.dataAxes,[ln1,ln2,ln4,ln6,ln5],{'ODBA','p(Nest)','Temp',ln6_label,'Sense'},...
                'AutoUpdate',"off",'fontsize',8);
            pos1 = get(app.dataAxes,'Position');
            hold(app.dataAxes,'off');
            app.SimilarityLabel.Text = sprintf("%1.1f%% total alike (%1.1f%% in view) - was %1.1f transitions/day, now %1.1f",...
                100*sum(app.binNestSense==ln6_data)/numel(ln6_data),...
                100*sum(app.binNestSense(useRange)==ln6_data(useRange))/numel(useRange),...
                sum(abs(diff(ln6_data))==1)/(numel(ln6_data)/86400),sum(abs(diff(app.binNestSense))==1)/(numel(ln6_data)/86400));
            
            
            yyaxis(app.compAxes,'left');
            lw = 2;
            plot(app.compAxes,t,app.sense.invOdba(useRange),'-','color',colors(1,:),'linewidth',lw);
            hold(app.compAxes,'on');
            plot(app.compAxes,t,app.sense.tempZ(useRange),'-','color',colors(2,:),'linewidth',lw);
            plot(app.compAxes,t,app.sense.tempRange(useRange),'-','color',colors(3,:),'linewidth',lw);
            plot(app.compAxes,t,app.sense.tempGradOdba(useRange),'-','color',colors(4,:),'linewidth',lw);
            plot(app.compAxes,t,app.sense.kmeansNest(useRange),'-','color',colors(5,:),'linewidth',lw);
            ylabel(app.compAxes,'Z');
            %             hold(app.compAxes,'off');
            if app.resetAxes
                xlim(app.compAxes,[min(t),max(t)]);
            end
            grid(app.compAxes,'on');
            plot(app.compAxes,t,app.sense.nest(useRange),'k-','linewidth',lw+1);
            legend(app.compAxes,{'invOdba','temp','tempRange','tempGradOdba','kmeansNest','p(Nest)'},...
                'AutoUpdate',"off",'fontsize',8); % InvO -> Grad
            yline(app.compAxes,0,'k:');
            ylim(app.compAxes,[-3 3]);
            pos2 = get(app.compAxes,'Position');
            set(app.compAxes,'Position',[pos2(1) pos2(2) pos1(3) pos2(4)]);
            linkaxes([app.dataAxes,app.compAxes],'x');
            hold(app.compAxes,'off');
            xlabel(app.dataAxes,'Time (min)');
            yyaxis(app.compAxes,'right');
            ylim(app.compAxes,[-2 2]);
            yticks(app.compAxes,[-1 1]);
            yticklabels(app.compAxes,{'Out','In'});
            set(app.compAxes,'ycolor','k');
            xlabel(app.compAxes,'Time (min)');
            
            nMin = 20;
            selectedButton = app.PeriEventDataButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Sense')
                [t,periOdba,periTemp] = periEventNest(app.binNestSense,app.sense.smOdba,app.sense.tempZ,nMin);
            else
                [t,periOdba,periTemp] = periEventNest(app.nest,app.sense.smOdba,app.sense.tempZ,nMin);
            end
            useAxes = {app.dataSuppAxes,app.compSuppAxes};
            for ii = 1:2
                yyaxis(useAxes{ii},'left');
                plot(useAxes{ii},t,mean(periOdba{ii}),'-','color',colors(1,:),'linewidth',3);
                hold(useAxes{ii},'on');
                plot(useAxes{ii},t,mean(periOdba{ii})+var(periOdba{ii}),':','color',colors(1,:),'linewidth',0.5);
                plot(useAxes{ii},t,mean(periOdba{ii})-var(periOdba{ii}),':','color',colors(1,:),'linewidth',0.5);
                hold(useAxes{ii},'off');
                ylim(useAxes{ii},'auto');
%                 ylim(useAxes{ii},[min(mean(periOdba{ii}))-std(mean(periOdba{ii})),max(mean(periOdba{ii}))+std(mean(periOdba{ii}))]);
                ylabel(useAxes{ii},'ODBA (Z)');
                grid(useAxes{ii},'on');
                
                yyaxis(useAxes{ii},'right');
                plot(useAxes{ii},t,mean(periTemp{ii}),'r-','linewidth',3);
                hold(useAxes{ii},'on');
                plot(useAxes{ii},t,mean(periTemp{ii})+var(periTemp{ii}),'r:','linewidth',0.5);
                plot(useAxes{ii},t,mean(periTemp{ii})-var(periTemp{ii}),'r:','linewidth',0.5);
                hold(useAxes{ii},'off');
                ylim(useAxes{ii},'auto');
%                 ylim(useAxes{ii},[min(mean(periTemp{ii}))-std(mean(periTemp{ii})),max(mean(periTemp{ii}))+std(mean(periTemp{ii}))]);
                xline(useAxes{ii},0,'k-');
                xlim(useAxes{ii},[min(t),max(t)]);
                xlabel(useAxes{ii},'Time (min)');
                ylabel(useAxes{ii},'Temp (Z)');
                grid(useAxes{ii},'on');
                set(useAxes{ii},'ycolor','red');
            end
            
            useAxes = {app.dataNestPeriods1,app.dataNestPeriods2};
            % treat beginning and end as transitions
            transLocs = unique([1;find(abs(diff(app.binNestSense))==1);numel(app.binNestSense)]);
            allPeriods = diff(transLocs);
            if app.binNestSense(1) == 0
                inNestPeriods = allPeriods(2:2:numel(allPeriods));
                outNestPeriods = allPeriods(1:2:numel(allPeriods));
            else
                inNestPeriods = allPeriods(1:2:numel(allPeriods));
                outNestPeriods = allPeriods(2:2:numel(allPeriods));
            end
            histRanges = [0,60;0 800];
            nBins = 30;
            for ii = 1:2
                histogram(useAxes{ii},inNestPeriods/60,linspace(histRanges(ii,1),histRanges(ii,2),nBins),'facecolor','k');
                hold(useAxes{ii},'on');
                histogram(useAxes{ii},outNestPeriods/60,linspace(histRanges(ii,1),histRanges(ii,2),nBins),'facecolor',colors(5,:));
                legend(useAxes{ii},["In Nest","Out Nest"]);
                hold(useAxes{ii},'off');
                xlabel(useAxes{ii},"Length (min)");
                ylabel(useAxes{ii},"Count");
                grid(useAxes{ii},'on');
            end
            
            app.resetAxes = false;
            app.Lamp.Color = app.colorOff;
        end
        
        function changeDay(app,byDays)
            dropItems = app.DayDropDown.Items;
            if ~isempty(dropItems)
                value = app.DayDropDown.Value;
                curIdx = find(strcmp(dropItems,value));
                newIdx = curIdx + byDays;
                if newIdx > 0 && newIdx <= numel(dropItems)
                    app.DayDropDown.Value = dropItems(newIdx);
                end
                app.resetAxes = true;
                updateData(app);
            end
        end
        
        function loadFileConfig(app)
            app.configPath = fullfile(app.PathEditField.Value,app.DataFileDropDown.Value+"."+app.saveFilename);
            if isfile(app.configPath)
                load(app.configPath,'senseParams');
                app.senseParams = senseParams; %#ok<ADPROP>
                setControls(app);
                app.LoadButton.Enable = true;
            else
                app.LoadButton.Enable = false;
            end
        end
        
        function listFiles(app)
            filepath = app.PathEditField.Value;
            files = dir(fullfile(filepath,"*.csv"));
            filenames = "";
            jj = 0;
            for ii = 1:numel(files)
                fname = fullfile(filepath,files(ii).name);
                fileID = fopen(fname);
                headerText = fgetl(fileID);
                if contains(headerText,'temp')
                    jj = jj + 1;
                    filenames(jj) = files(ii).name;
                else
                    fprintf("No temp %s\n",files(ii).name);
                end
            end
            app.DataFileDropDown.Items = filenames;
            save(app.cacheFilename,'filepath');
        end
        
        function setSenseParams(app)
            app.senseParams.thresh = app.SenseThreshSlider.Value;
            app.senseParams.w_kmeans = app.KmeanswSlider.Value;
            app.senseParams.w_temp = app.TempwSlider.Value;
            app.senseParams.w_tempGrad = app.TempGradwSlider.Value;
            app.senseParams.w_odba = app.ODBAwSlider.Value;
            app.senseParams.sm_tempGrad = app.SmTempGradSlider.Value;
            app.senseParams.sm_odba = app.SmODBASlider.Value;
            app.senseParams.fixTransitions = app.FixTransitionsCheckBox.Value;
            if isempty(app.senseParams_default)
                app.senseParams_default = app.senseParams;
            end
        end
        
        function setControls(app)
            app.SenseThreshSlider.Value = app.senseParams.thresh;
            app.KmeanswSlider.Value = app.senseParams.w_kmeans;
            app.TempwSlider.Value = app.senseParams.w_temp;
            app.TempGradwSlider.Value = app.senseParams.w_tempGrad;
            app.ODBAwSlider.Value = app.senseParams.w_odba;
            app.SmTempGradSlider.Value = app.senseParams.sm_tempGrad;
            app.sense.smOdbaSlider.Value = app.senseParams.sm_odba;
            app.FixTransitionsCheckBox.Value = app.senseParams.fixTransitions;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isfile(app.cacheFilename)
                load(app.cacheFilename,'filepath');
                app.PathEditField.Value = filepath;
            end
            listFiles(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            close all;
            app.Lamp.Color = app.colorOn;
            pause(0.1);
            fname = fullfile(app.PathEditField.Value,app.DataFileDropDown.Value);
            app.T = readtable(fname);
            app.Lamp.Color = app.colorOff;
            nDays = floor(size(app.T,1) / 86400);
            app.DayDropDown.Items = compose("%i",1:nDays);
            app.resetAxes = true;
            app.binNestSense = [];
            [app.temp,app.nest] = getTempAndNest(app.T.temp,60);
            app.odba = app.T.odba;
            app.senseParams.offset_odba = findZOffset(app.temp,app.odba,app.nest);
            loadFileConfig(app);
            adjustFile = "adjustSession_"+app.DataFileDropDown.Value+".mat";
            if isfile(adjustFile)
                load(adjustFile,'adj_binNestSense');
                app.adj_binNestSense = adj_binNestSense; %#ok<ADPROPLC> 
            else
                app.adj_binNestSense = [];
            end
            updateData(app);
        end

        % Value changed function: DayDropDown
        function DayDropDownValueChanged(app, event)
            app.resetAxes = true;
            updateData(app);
        end

        % Button pushed function: RecalcButton
        function RecalcButtonPushed(app, event)
            updateData(app);
        end

        % Value changed function: TempwSlider
        function TempwSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: TempGradwSlider
        function TempGradwSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: ODBAwSlider
        function ODBAwSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: SmTempGradSlider
        function SmTempGradSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: SmODBASlider
        function SmODBASliderValueChanged(app, event)
            updateData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetAxes = true;
            updateData(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            senseParams = app.senseParams; %#ok<ADPROPLC> 
            save(app.configPath,'senseParams');
            loadFileConfig(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loadFileConfig(app);
            updateData(app);
        end

        % Value changed function: KmeanswSlider
        function KmeanswSliderValueChanged(app, event)
            updateData(app);
        end

        % Button pushed function: PrevDay
        function PrevDayPushed(app, event)
            changeDay(app,-1);
        end

        % Button pushed function: NextDay
        function NextDayPushed(app, event)
            changeDay(app,1);
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            listFiles(app);
        end

        % Button pushed function: PasteButton
        function PasteButtonPushed(app, event)
            app.PathEditField.Value = clipboard('paste');
            listFiles(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            binNestSense = app.binNestSense; %#ok<ADPROPLC>
            setSenseParams(app);
            senseParams = app.senseParams; %#ok<ADPROPLC>
            sense = app.sense; %#ok<ADPROPLC> 
            nest = app.nest; %#ok<ADPROPLC> 
            odba = app.odba; %#ok<ADPROPLC>
            curXlim = xlim(app.compAxes);
            useRange = round(curXlim(1)*60:curXlim(2)*60)+1; % good enough
            app.Lamp.Color = app.colorOn;
            pause(0.1);
            save("algorithmApp_session.mat",'senseParams','binNestSense','sense','odba','nest','useRange');
            app.Lamp.Color = app.colorOff;
        end

        % Value changed function: SenseThreshSlider
        function SenseThreshSliderValueChanged(app, event)
            updateData(app);
        end

        % Value changed function: FixTransitionsCheckBox
        function FixTransitionsCheckBoxValueChanged(app, event)
            updateData(app);
        end

        % Selection changed function: PeriEventDataButtonGroup
        function PeriEventDataButtonGroupSelectionChanged(app, event)
            updateData(app);
        end

        % Button pushed function: DefaultsButton
        function DefaultsButtonPushed(app, event)
            app.senseParams = app.senseParams_default;
            setControls(app);
            updateData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.dataAxes = uiaxes(app.UIFigure);
            title(app.dataAxes, 'Main Data')
            xlabel(app.dataAxes, 'X')
            ylabel(app.dataAxes, 'Y')
            zlabel(app.dataAxes, 'Z')
            app.dataSuppAxes = uiaxes(app.UIFigure);
            title(app.dataSuppAxes, 'Peri-Enter Nest')
            xlabel(app.dataSuppAxes, 'X')
            ylabel(app.dataSuppAxes, 'Y')
            zlabel(app.dataSuppAxes, 'Z')
            app.compAxes = uiaxes(app.UIFigure);
            title(app.compAxes, 'Algorithm Components')
            xlabel(app.compAxes, 'X')
            ylabel(app.compAxes, 'Y')
            zlabel(app.compAxes, 'Z')
            app.compSuppAxes = uiaxes(app.UIFigure);
            title(app.compSuppAxes, 'Peri-Exit Nest')
            xlabel(app.compSuppAxes, 'X')
            ylabel(app.compSuppAxes, 'Y')
            zlabel(app.compSuppAxes, 'Z')
            app.dataNestPeriods1 = uiaxes(app.UIFigure);
            title(app.dataNestPeriods1, 'All Nest Periods')
            xlabel(app.dataNestPeriods1, 'X')
            ylabel(app.dataNestPeriods1, 'Y')
            zlabel(app.dataNestPeriods1, 'Z')
            app.dataNestPeriods2 = uiaxes(app.UIFigure);
            title(app.dataNestPeriods2, 'Short Nest Periods')
            xlabel(app.dataNestPeriods2, 'X')
            ylabel(app.dataNestPeriods2, 'Y')
            zlabel(app.dataNestPeriods2, 'Z')
            app.DataFileDropDownLabel = uilabel(app.UIFigure);
            app.DataFileDropDownLabel.Text = 'Data File';
            app.DataFileDropDown = uidropdown(app.UIFigure);
            app.DataFileDropDown.Items = {};
            app.DataFileDropDown.Value = {};
            app.TempwSliderLabel = uilabel(app.UIFigure);
            app.TempwSliderLabel.Text = 'Temp (w)';
            app.TempwSlider = uislider(app.UIFigure);
            app.TempwSlider.Limits = [0 4];
            app.TempwSlider.MajorTicks = [0 1 2 3 4];
            app.TempwSlider.ValueChangedFcn = createCallbackFcn(app, @TempwSliderValueChanged, true);
            app.TempwSlider.MinorTicks = [];
            app.TempwSlider.Value = 1;
            app.DayDropDownLabel = uilabel(app.UIFigure);
            app.DayDropDownLabel.Text = 'Day';
            app.DayDropDown = uidropdown(app.UIFigure);
            app.DayDropDown.Items = {};
            app.DayDropDown.ValueChangedFcn = createCallbackFcn(app, @DayDropDownValueChanged, true);
            app.DayDropDown.Value = {};
            app.TempGradwSliderLabel = uilabel(app.UIFigure);
            app.TempGradwSliderLabel.Text = 'Temp Grad (w)';
            app.TempGradwSlider = uislider(app.UIFigure);
            app.TempGradwSlider.Limits = [0 4];
            app.TempGradwSlider.MajorTicks = [0 1 2 3 4];
            app.TempGradwSlider.ValueChangedFcn = createCallbackFcn(app, @TempGradwSliderValueChanged, true);
            app.TempGradwSlider.MinorTicks = [];
            app.TempGradwSlider.Value = 1;
            app.ODBAwSliderLabel = uilabel(app.UIFigure);
            app.ODBAwSliderLabel.Text = 'ODBA (w)';
            app.ODBAwSlider = uislider(app.UIFigure);
            app.ODBAwSlider.Limits = [0 4];
            app.ODBAwSlider.MajorTicks = [0 1 2 3 4];
            app.ODBAwSlider.ValueChangedFcn = createCallbackFcn(app, @ODBAwSliderValueChanged, true);
            app.ODBAwSlider.MinorTicks = [];
            app.ODBAwSlider.Value = 1;
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Lamp = uilamp(app.UIFigure);
            app.SmTempGradSliderLabel = uilabel(app.UIFigure);
            app.SmTempGradSliderLabel.Text = 'Sm Temp Grad';
            app.SmTempGradSlider = uislider(app.UIFigure);
            app.SmTempGradSlider.Limits = [1 20];
            app.SmTempGradSlider.MajorTicks = [1 5 10 20];
            app.SmTempGradSlider.ValueChangedFcn = createCallbackFcn(app, @SmTempGradSliderValueChanged, true);
            app.SmTempGradSlider.MinorTicks = [];
            app.SmTempGradSlider.Value = 10;
            app.SmODBASliderLabel = uilabel(app.UIFigure);
            app.SmODBASliderLabel.Text = 'Sm ODBA';
            app.SmODBASlider = uislider(app.UIFigure);
            app.SmODBASlider.Limits = [1 20];
            app.SmODBASlider.MajorTicks = [1 5 10 20];
            app.SmODBASlider.ValueChangedFcn = createCallbackFcn(app, @SmODBASliderValueChanged, true);
            app.SmODBASlider.MinorTicks = [];
            app.SmODBASlider.Value = 15;
            app.RecalcButton = uibutton(app.UIFigure, 'push');
            app.RecalcButton.ButtonPushedFcn = createCallbackFcn(app, @RecalcButtonPushed, true);
            app.RecalcButton.Text = 'Re-calc';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.SimilarityLabel = uilabel(app.UIFigure);
            app.SimilarityLabel.Text = 'Similarity';
            app.KmeanswSliderLabel = uilabel(app.UIFigure);
            app.KmeanswSliderLabel.Text = 'Kmeans (w)';
            app.KmeanswSlider = uislider(app.UIFigure);
            app.KmeanswSlider.Limits = [0 4];
            app.KmeanswSlider.MajorTicks = [0 1 2 3 4];
            app.KmeanswSlider.ValueChangedFcn = createCallbackFcn(app, @KmeanswSliderValueChanged, true);
            app.KmeanswSlider.MinorTicks = [];
            app.NextDay = uibutton(app.UIFigure, 'push');
            app.NextDay.ButtonPushedFcn = createCallbackFcn(app, @NextDayPushed, true);
            app.NextDay.Text = '>>';
            app.PrevDay = uibutton(app.UIFigure, 'push');
            app.PrevDay.ButtonPushedFcn = createCallbackFcn(app, @PrevDayPushed, true);
            app.PrevDay.Text = '<<';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.PathEditField.Value = '/Volumes/GAIDICASSD/KRSP/KRSP Axy Data/Emily Studds Axy/September 2016';
            app.PasteButton = uibutton(app.UIFigure, 'push');
            app.PasteButton.ButtonPushedFcn = createCallbackFcn(app, @PasteButtonPushed, true);
            app.PasteButton.Text = 'Paste';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SenseThreshSliderLabel = uilabel(app.UIFigure);
            app.SenseThreshSliderLabel.Text = 'Sense Thresh';
            app.SenseThreshSlider = uislider(app.UIFigure);
            app.SenseThreshSlider.Limits = [0 1];
            app.SenseThreshSlider.MajorTicks = [0 0.5 1];
            app.SenseThreshSlider.ValueChangedFcn = createCallbackFcn(app, @SenseThreshSliderValueChanged, true);
            app.SenseThreshSlider.MinorTicks = [];
            app.SenseThreshSlider.Value = 0.2;
            app.FixTransitionsCheckBox = uicheckbox(app.UIFigure);
            app.FixTransitionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixTransitionsCheckBoxValueChanged, true);
            app.FixTransitionsCheckBox.Text = 'Fix Transitions';
            app.FixTransitionsCheckBox.Value = true;
            app.PeriEventDataButtonGroup = uibuttongroup(app.UIFigure);
            app.PeriEventDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriEventDataButtonGroupSelectionChanged, true);
            app.PeriEventDataButtonGroup.Title = 'Peri Event Data';
            app.SenseButton = uiradiobutton(app.PeriEventDataButtonGroup);
            app.SenseButton.Text = 'Sense';
            app.SenseButton.Value = true;
            app.NestButton = uiradiobutton(app.PeriEventDataButtonGroup);
            app.NestButton.Text = 'Nest';
            app.DefaultsButton = uibutton(app.UIFigure, 'push');
            app.DefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultsButtonPushed, true);
            app.DefaultsButton.Text = 'Defaults';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = algorithmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end