classdef axySelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PathEditField         matlab.ui.control.EditField
        PathEditFieldLabel    matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        DatesListBox          matlab.ui.control.ListBox
        DatesListBoxLabel     matlab.ui.control.Label
        SmoothSpinner         matlab.ui.control.Spinner
        SmoothSpinnerLabel    matlab.ui.control.Label
        RandomButton          matlab.ui.control.Button
        SelectFileButton      matlab.ui.control.Button
        t3nSpinnerLabel       matlab.ui.control.Label
        t3nSpinner            matlab.ui.control.Spinner
        t2nSpinnerLabel       matlab.ui.control.Label
        t2nSpinner            matlab.ui.control.Spinner
        t1nSpinnerLabel       matlab.ui.control.Label
        t1nSpinner            matlab.ui.control.Spinner
        t0SpinnerLabel        matlab.ui.control.Label
        t0Spinner             matlab.ui.control.Spinner
        t1nSpinner_2Label     matlab.ui.control.Label
        t1nSpinner_2          matlab.ui.control.Spinner
        WThreshSliderLabel    matlab.ui.control.Label
        WThreshSlider         matlab.ui.control.Slider
        nminutesSpinnerLabel  matlab.ui.control.Label
        nminutesSpinner       matlab.ui.control.Spinner
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lns
        T
        odba
        odba_max
        odba_z
        temp
        asleep
        awake
        W
        dt
        thresh_lns
        colors = lines(6)
        odba_color
        temp_color
        vert_lw = 1.25
        thresh_vals
        ln_styles
        ln_colors
        allDates
        dtDays
        nRange
        useDay
    end
    
    methods (Access = private)
        
        function loadData(app)
            % init
            app.odba_color = app.colors(5,:);
            app.temp_color = app.colors(2,:);
            app.ln_styles = {':',':','-','-'};
            app.ln_colors = [0 0 0;1 0 0;1 0 0;0 0 0];
            app.UIAxes.Visible = true;
            app.DatesListBox.Enable = true;
            % load T
            if isempty(app.NameEditField.Value)
                load("axySleepTestFile.mat","-mat","T");
            else
                load(fullfile(app.PathEditField.Value,app.NameEditField.Value),"-mat","T");
            end
            
            [app.T,app.W] = detect_sleepWake(T);
            reloadT(app);
            app.dtDays = datetime(year(app.T.datetime),month(app.T.datetime),day(app.T.datetime));
            app.allDates = cellstr(datestr(unique(app.dtDays)));
            app.DatesListBox.Items = app.allDates;
            
            for iDay = 1:numel(app.allDates)
                dayIds = find(app.dtDays == app.allDates{iDay});
                app.nRange(iDay,:) = [min(dayIds),max(dayIds)]; % real range
            end
            app.useDay = 1;
            app.changeDay();
        end
        
        function reloadT(app)
            [app.T,app.W] = detect_sleepWake(app.T,...
                app.SmoothSpinner.Value,...
                app.nminutesSpinner.Value,...
                app.t3nSpinner.Value,...
                app.t2nSpinner.Value,...
                app.t1nSpinner.Value,...
                app.t0Spinner.Value,...
                app.t1nSpinner_2.Value,...
                app.WThreshSlider.Value);
            app.odba = app.T.odba;
            app.odba_max = app.T.odba_max;
            app.odba_z = app.T.odba_z;
            app.temp = app.T.temp;
            app.dt = app.T.datetime;
            app.asleep = app.T.asleep;
            app.awake = app.T.awake;
        end
        
        function changeDay(app)
            app.UIAxes.Title.String = app.allDates{app.useDay};
            app.DatesListBox.Value = app.allDates{app.useDay};
            app.plotDay();
        end
        
        function plotDay(app)
            dayRange = app.nRange(app.useDay,1):app.nRange(app.useDay,2);
            lw = 1;
            op = 0.4;
            % Plot 1
            app.lns = [];
            yyaxis(app.UIAxes,"left");
            hold(app.UIAxes,"off"); % off at first to clear previous lines
            app.lns(1) = plot(app.UIAxes,dayRange,app.odba(dayRange),'-','linewidth',lw,'color',[app.odba_color,op]);
            set(app.UIAxes,'ycolor',app.odba_color);
            set(app.UIAxes,'xlim',app.nRange(app.useDay,:));
            set(app.UIAxes,'xtick',linspace(dayRange(1),dayRange(end),25));
            set(app.UIAxes,'xticklabel',round(linspace(0,24,25)));
            set(app.UIAxes,'xcolor',repmat(0.5,[1,3]));
            
            hold(app.UIAxes,"on");
            app.lns(2) = plot(app.UIAxes,dayRange,app.odba_max(dayRange),'-','linewidth',lw-0.5,'color',[app.odba_color,op-.2]);
            height = max(ylim(app.UIAxes))/10;
            offset = max(ylim(app.UIAxes))/2 - height;
            app.lns(4) = plot(app.UIAxes,dayRange,app.awake(dayRange)*height+offset,'-','linewidth',lw,'color','k');
            set(app.UIAxes,'ytick',sort([ylim(app.UIAxes),offset]));
            set(app.UIAxes,'yticklabel',{num2str(min(ylim(app.UIAxes))),"zZ",num2str(max(ylim(app.UIAxes)))});
            hold(app.UIAxes,"off");
            
            yyaxis(app.UIAxes,"right");
            hold(app.UIAxes,"off");
            app.lns(3) = plot(app.UIAxes,dayRange,app.temp(dayRange),'-','linewidth',lw,'color',[app.temp_color,op]);
            hold(app.UIAxes,"on");
            set(app.UIAxes,'ycolor',app.temp_color);
            set(app.UIAxes,'ytick',ylim(app.UIAxes));
            ylabel(app.UIAxes,'temp (C)')
            
            legend(app.UIAxes,app.lns,{'ODBA','ODBA_{max}','Temp','Awake'},'box','off','location','northwest');
            
            % Plot 2
            app.lns = [];
            yyaxis(app.UIAxes_2,"left");
            hold(app.UIAxes_2,"off"); % off at first to clear previous lines
            odba_z_color = repmat(0.2,[4,1]);
            app.lns(1) = plot(app.UIAxes_2,dayRange,app.odba_z(dayRange),'-','linewidth',lw-0.5,'color',odba_z_color);
            set(app.UIAxes_2,'ycolor',odba_z_color);
            set(app.UIAxes_2,'xlim',app.nRange(app.useDay,:));
            set(app.UIAxes_2,'xtick',linspace(dayRange(1),dayRange(end),25));
            set(app.UIAxes_2,'xticklabel',round(linspace(0,24,25)));
            set(app.UIAxes_2,'xcolor',repmat(0.5,[1,3]));
            set(app.UIAxes_2,'ytick',ylim(app.UIAxes_2));
%             set(app.UIAxes_2,'ylabel',ylim(app.UIAxes));
            hold(app.UIAxes_2,"on");
            
            yyaxis(app.UIAxes_2,"right");
            hold(app.UIAxes_2,"off");
            app.lns(2) = plot(app.UIAxes_2,dayRange,app.W(dayRange),'-','linewidth',lw,'color','k');
            set(app.UIAxes_2,'ylim',[-3 12]); % emperical
            set(app.UIAxes_2,'ytick',sort([ylim(app.UIAxes_2),0]));
            hold(app.UIAxes_2,"on"); % more plots below
            app.lns(3) = plot(app.UIAxes_2,xlim(app.UIAxes_2),[app.WThreshSlider.Value,app.WThreshSlider.Value],'r:');
            ylabel(app.UIAxes_2,'W')
            set(app.UIAxes_2,'ycolor','k');
            legend(app.UIAxes_2,app.lns,{'ODBA_Z','W','W_{Thresh}'},'box','off','location','northwest');
            
            linkaxes([app.UIAxes,app.UIAxes_2],'x')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadData(app)
        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
            files = dir(fullfile(app.PathEditField.Value,'*.mat'));
            useFile = files(randi([1 size(files,1)])).name;
            app.NameEditField.Value = useFile;
            app.loadData();
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file,path] = uigetfile(fullfile(app.PathEditField.Value,'*.mat'));
            if file ~= 0
                app.PathEditField.Value = path;
                app.NameEditField.Value = file;
                app.loadData();
            end
        end

        % Callback function
        function EnterNestSliderValueChanged(app, event)
            value = app.EnterNestSlider.Value;
            app.update_thresh(3,value);
        end

        % Callback function
        function AsleepSliderValueChanged(app, event)
            value = app.AsleepSlider.Value;
            app.update_thresh(4,value);
        end

        % Callback function
        function AwakeSliderValueChanged(app, event)
            value = app.AwakeSlider.Value;
            app.update_thresh(1,value);
        end

        % Callback function
        function ExitNestSliderValueChanged(app, event)
            value = app.ExitNestSlider.Value;
            app.update_thresh(2,value);
        end

        % Value changed function: DatesListBox
        function DatesListBoxValueChanged(app, event)
            value = app.DatesListBox.Value;
            app.useDay = find(strcmp(app.allDates,value));
            app.changeDay();
        end

        % Callback function
        function nFiltEditFieldValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Value changed function: SmoothSpinner
        function SmoothSpinnerValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Callback function
        function t4SpinnerValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Value changed function: t3nSpinner
        function t3nSpinnerValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Value changed function: t2nSpinner
        function t2nSpinnerValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Value changed function: t1nSpinner
        function t1nSpinnerValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Value changed function: t0Spinner
        function t0SpinnerValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Value changed function: t1nSpinner_2
        function t1nSpinner_2ValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Callback function
        function t2Spinner_2ValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Value changed function: WThreshSlider
        function WThreshSliderValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end

        % Callback function
        function nminutesSpinnerValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: nminutesSpinner
        function nminutesSpinnerValueChanged(app, event)
            reloadT(app);
            plotDay(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = '/Users/matt/Box Sync/KRSP Axy Data/Temp';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Editable = 'off';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.DatesListBox = uilistbox(app.UIFigure);
            app.DatesListBox.Items = {};
            app.DatesListBox.ValueChangedFcn = createCallbackFcn(app, @DatesListBoxValueChanged, true);
            app.DatesListBox.Enable = 'off';
            app.DatesListBox.Value = {};
            app.DatesListBoxLabel = uilabel(app.UIFigure);
            app.DatesListBoxLabel.Enable = 'off';
            app.DatesListBoxLabel.Text = 'Dates';
            app.SmoothSpinner = uispinner(app.UIFigure);
            app.SmoothSpinner.Limits = [1 30];
            app.SmoothSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothSpinnerValueChanged, true);
            app.SmoothSpinner.Value = 2;
            app.SmoothSpinnerLabel = uilabel(app.UIFigure);
            app.SmoothSpinnerLabel.Text = 'Smooth';
            app.RandomButton = uibutton(app.UIFigure, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.t3nSpinnerLabel = uilabel(app.UIFigure);
            app.t3nSpinnerLabel.Text = 't-3*n';
            app.t3nSpinner = uispinner(app.UIFigure);
            app.t3nSpinner.Step = 0.05;
            app.t3nSpinner.Limits = [0 1];
            app.t3nSpinner.ValueChangedFcn = createCallbackFcn(app, @t3nSpinnerValueChanged, true);
            app.t3nSpinner.Value = 0.15;
            app.t2nSpinnerLabel = uilabel(app.UIFigure);
            app.t2nSpinnerLabel.Text = {'t-2*n'; ''};
            app.t2nSpinner = uispinner(app.UIFigure);
            app.t2nSpinner.Step = 0.05;
            app.t2nSpinner.Limits = [0 1];
            app.t2nSpinner.ValueChangedFcn = createCallbackFcn(app, @t2nSpinnerValueChanged, true);
            app.t2nSpinner.Value = 0.15;
            app.t1nSpinnerLabel = uilabel(app.UIFigure);
            app.t1nSpinnerLabel.Text = {'t-1*n'; ''};
            app.t1nSpinner = uispinner(app.UIFigure);
            app.t1nSpinner.Step = 0.05;
            app.t1nSpinner.Limits = [0 1];
            app.t1nSpinner.ValueChangedFcn = createCallbackFcn(app, @t1nSpinnerValueChanged, true);
            app.t1nSpinner.Value = 0.1;
            app.t0SpinnerLabel = uilabel(app.UIFigure);
            app.t0SpinnerLabel.Text = 't=0';
            app.t0Spinner = uispinner(app.UIFigure);
            app.t0Spinner.Step = 0.05;
            app.t0Spinner.Limits = [0 1];
            app.t0Spinner.ValueChangedFcn = createCallbackFcn(app, @t0SpinnerValueChanged, true);
            app.t0Spinner.Value = 0.15;
            app.t1nSpinner_2Label = uilabel(app.UIFigure);
            app.t1nSpinner_2Label.Text = {'t+1*n'; ''};
            app.t1nSpinner_2 = uispinner(app.UIFigure);
            app.t1nSpinner_2.Step = 0.05;
            app.t1nSpinner_2.Limits = [0 1];
            app.t1nSpinner_2.ValueChangedFcn = createCallbackFcn(app, @t1nSpinner_2ValueChanged, true);
            app.t1nSpinner_2.Value = 0.15;
            app.WThreshSliderLabel = uilabel(app.UIFigure);
            app.WThreshSliderLabel.Text = 'W Thresh';
            app.WThreshSlider = uislider(app.UIFigure);
            app.WThreshSlider.Limits = [-3 12];
            app.WThreshSlider.MajorTickLabels = {''};
            app.WThreshSlider.ValueChangedFcn = createCallbackFcn(app, @WThreshSliderValueChanged, true);
            app.WThreshSlider.MinorTicks = [];
            app.nminutesSpinnerLabel = uilabel(app.UIFigure);
            app.nminutesSpinnerLabel.Text = 'n minutes';
            app.nminutesSpinner = uispinner(app.UIFigure);
            app.nminutesSpinner.Limits = [1 20];
            app.nminutesSpinner.ValueChangedFcn = createCallbackFcn(app, @nminutesSpinnerValueChanged, true);
            app.nminutesSpinner.Value = 1;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'hour of day')
            ylabel(app.UIAxes_2, 'ODBA (Z-score)')
            app.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'hour of day')
            ylabel(app.UIAxes, 'ODBA (raw)')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = axySelect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end